classdef funcUploadButtonPushedChecksData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        CustomTab                       matlab.ui.container.Tab
        TabGroup_3                      matlab.ui.container.TabGroup
        S1Tab_2                         matlab.ui.container.Tab
        UploadButton                    matlab.ui.control.Button
        FileLocationEditField           matlab.ui.control.EditField
        FileLocationEditFieldLabel      matlab.ui.control.Label
        S2Tab_2                         matlab.ui.container.Tab
        FileLocationEditField_2         matlab.ui.control.EditField
        FileLocationEditField_2Label    matlab.ui.control.Label
        UploadButton_2                  matlab.ui.control.Button
        PredefinedTab                   matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        xTab                            matlab.ui.container.Tab
        SetParametersButtonX            matlab.ui.control.Button
        ChooseFunctionDropDownX         matlab.ui.control.DropDown
        ChooseFunctionDropDownLabel     matlab.ui.control.Label
        yTab                            matlab.ui.container.Tab
        ChooseFunctionDropDownY         matlab.ui.control.DropDown
        ChooseFunctionDropDown_12Label  matlab.ui.control.Label
        SetParametersButtonY            matlab.ui.control.Button
        zTab                            matlab.ui.container.Tab
        ChooseFunctionDropDownZ         matlab.ui.control.DropDown
        ChooseFunctionDropDown_13Label  matlab.ui.control.Label
        SetParametersButtonZ            matlab.ui.control.Button
        yawTab                          matlab.ui.container.Tab
        ChooseFunctionDropDownYaw       matlab.ui.control.DropDown
        ChooseFunctionDropDown_14Label  matlab.ui.control.Label
        SetParametersButtonYaw          matlab.ui.control.Button
        pitchTab                        matlab.ui.container.Tab
        ChooseFunctionDropDownPitch     matlab.ui.control.DropDown
        ChooseFunctionDropDown_15Label  matlab.ui.control.Label
        SetParametersButtonPitch        matlab.ui.control.Button
        rollTab                         matlab.ui.container.Tab
        ChooseFunctionDropDownRoll      matlab.ui.control.DropDown
        ChooseFunctionDropDown_16Label  matlab.ui.control.Label
        SetParametersButtonRoll         matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        VerifyMotionLabel               matlab.ui.control.Label
        MotionFileLabel                 matlab.ui.control.Label
        TabGroup_2                      matlab.ui.container.TabGroup
        S2toS1Tab                       matlab.ui.container.Tab
        SaveValuesButton_3              matlab.ui.control.Button
        rollEditField                   matlab.ui.control.NumericEditField
        rollEditFieldLabel              matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        pitchEditField                  matlab.ui.control.NumericEditField
        pitchEditFieldLabel             matlab.ui.control.Label
        yawEditField_2                  matlab.ui.control.NumericEditField
        yawEditField_2Label             matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        InitialRelativePositionofTargetsrelativetoS1Label  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        S1Tab                           matlab.ui.container.Tab
        SaveValuesButton                matlab.ui.control.Button
        P2yEditField                    matlab.ui.control.NumericEditField
        P2yEditFieldLabel               matlab.ui.control.Label
        P2xEditField                    matlab.ui.control.NumericEditField
        P2xEditFieldLabel               matlab.ui.control.Label
        P1yEditField                    matlab.ui.control.NumericEditField
        P1yEditFieldLabel               matlab.ui.control.Label
        P1xEditField                    matlab.ui.control.NumericEditField
        P1xEditFieldLabel               matlab.ui.control.Label
        S2Tab                           matlab.ui.container.Tab
        SaveValuesButton_2              matlab.ui.control.Button
        P2yEditField_4                  matlab.ui.control.NumericEditField
        P2yEditField_4Label             matlab.ui.control.Label
        P2xEditField_4                  matlab.ui.control.NumericEditField
        P2xEditField_4Label             matlab.ui.control.Label
        P1yEditField_4                  matlab.ui.control.NumericEditField
        P1yEditField_4Label             matlab.ui.control.Label
        P1xEditField_4                  matlab.ui.control.NumericEditField
        P1xEditField_4Label             matlab.ui.control.Label
        CoordinatesofStewartPlatformsS1andS2Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
           % Open file dialog
            [fileName, filePath] = uigetfile({'*.txt;*.csv', 'Text files (*.txt, *.csv)'; '*.*', 'All files (*.*)'}, 'Select Data File');
            
            % Check if the user selected a file
            if fileName ~= 0
                try
                    % Read data from the file
                    data = dlmread(fullfile(filePath, fileName));
            
                    % Extract the first row as NumberOfDataPoints
                    NumberOfDataPoints = data(1);
            
                    % Extract the second row as SamplingRate
                    SamplingRate = data(2);
            
                    % Extract columns after the second row as separate variables
                    XData = data(3:end, 1);
                    YData = data(3:end, 2);
                    ZData = data(3:end, 3);
                    YawData = data(3:end, 4);
                    PitchData = data(3:end, 5);
                    RollData = data(3:end, 6);

                    % Check for empty columns
                    isEmptyXData = isempty(XData);
                    isEmptyYData = isempty(YData);
                    isEmptyZData = isempty(ZData);
                    isEmptyYawData = isempty(YawData);
                    isEmptyPitchData = isempty(PitchData);
                    isEmptyRollData = isempty(RollData);
            
                    if isEmptyXData
                        warndlg('Warning: XData is empty.');
                    end
            
                    if isEmptyYData
                        warndlg('Warning: YData is empty.');
                    end
            
                    if isEmptyZData
                        warndlg('Warning: ZData is empty.');
                    end
            
                    if isEmptyYawData
                        warndlg('Warning: YawData is empty.');
                    end
            
                    if isEmptyPitchData
                        warndlg('Warning: PitchData is empty.');
                    end
            
                    if isEmptyRollData
                        warndlg('Warning: RollData is empty.');
                    end

                  
                    % Check for valid values
                    isValidXData = all(~isnan(XData));
                    isValidYData = all(~isnan(YData));
                    isValidZData = all(~isnan(ZData));
                    isValidYawData = all(~isnan(YawData));
                    isValidPitchData = all(~isnan(PitchData));
                    isValidRollData = all(~isnan(RollData));
            
                    % Check the number of data points
                    isValidNumberOfDataPoints = NumberOfDataPoints == length(XData);
                                
                    % Display validity and data types
                    if isValidXData && isValidYData && isValidZData && isValidYawData && isValidPitchData && isValidRollData && isValidNumberOfDataPoints
                        disp('All values are valid.');
                    else
                        disp('Warning: Some values are not valid or inconsistent.');
                    end
            
                    fprintf('Data type of XData: %s\n', class(XData));
                    fprintf('Data type of YData: %s\n', class(YData));
                    fprintf('Data type of ZData: %s\n', class(ZData));
                    fprintf('Data type of YawData: %s\n', class(YawData));
                    fprintf('Data type of PitchData: %s\n', class(PitchData));
                    fprintf('Data type of RollData: %s\n', class(RollData));
            
                    % Display warnings for inconsistency or empty columns
                    if ~isValidNumberOfDataPoints
                        warndlg('Warning: Inconsistent number of data points.');
                    end
                                
            
                catch
                    % Display a warning pop-up message if an error occurs
                    warndlg('Error reading the motion file data. Missing a column of data or data contains non-numerical value.');
                end
            end

        end

        % Button pushed function: UploadButton_2
        function UploadButton_2Pushed(app, event)
            % Open file dialog
            [fileName, filePath] = uigetfile({'*.txt;*.csv', 'Text files (*.txt, *.csv)'; '*.*', 'All files (*.*)'}, 'Select Data File');
            
            % Check if the user selected a file
            if fileName ~= 0
                try
                    % Read data from the file
                    data2 = dlmread(fullfile(filePath, fileName));
            
                    % Extract the first row as NumberOfDataPoints
                    NumberOfDataPoints2 = data2(1);
            
                    % Extract the second row as SamplingRate
                    SamplingRate2 = data2(2);
            
                    % Extract columns after the second row as separate variables
                    XData2 = data2(3:end, 1);
                    YData2 = data2(3:end, 2);
                    ZData2 = data2(3:end, 3);
                    YawData2 = data2(3:end, 4);
                    PitchData2 = data2(3:end, 5);
                    RollData2 = data2(3:end, 6);

                    % Check for empty columns
                    isEmptyXData = isempty(XData2);
                    isEmptyYData = isempty(YData2);
                    isEmptyZData = isempty(ZData2);
                    isEmptyYawData = isempty(YawData2);
                    isEmptyPitchData = isempty(PitchData2);
                    isEmptyRollData = isempty(RollData2);
            
                    if isEmptyXData
                        warndlg('Warning: XData is empty.');
                    end
            
                    if isEmptyYData
                        warndlg('Warning: YData is empty.');
                    end
            
                    if isEmptyZData
                        warndlg('Warning: ZData is empty.');
                    end
            
                    if isEmptyYawData
                        warndlg('Warning: YawData is empty.');
                    end
            
                    if isEmptyPitchData
                        warndlg('Warning: PitchData is empty.');
                    end
            
                    if isEmptyRollData
                        warndlg('Warning: RollData is empty.');
                    end

                  
                    % Check for valid values
                    isValidXData = all(~isnan(XData2));
                    isValidYData = all(~isnan(YData2));
                    isValidZData = all(~isnan(ZData2));
                    isValidYawData = all(~isnan(YawData2));
                    isValidPitchData = all(~isnan(PitchData2));
                    isValidRollData = all(~isnan(RollData2));
            
                    % Check the number of data points
                    isValidNumberOfDataPoints = NumberOfDataPoints2 == length(XData2);
                                
                    % Display validity and data types
                    if isValidXData && isValidYData && isValidZData && isValidYawData && isValidPitchData && isValidRollData && isValidNumberOfDataPoints
                        disp('All values are valid.');
                    else
                        disp('Warning: Some values are not valid or inconsistent.');
                    end
            
                    fprintf('Data type of XData: %s\n', class(XData2));
                    fprintf('Data type of YData: %s\n', class(YData2));
                    fprintf('Data type of ZData: %s\n', class(ZData2));
                    fprintf('Data type of YawData: %s\n', class(YawData2));
                    fprintf('Data type of PitchData: %s\n', class(PitchData2));
                    fprintf('Data type of RollData: %s\n', class(RollData2));
            
                    % Display warnings for inconsistency or empty columns
                    if ~isValidNumberOfDataPoints
                        warndlg('Warning: Inconsistent number of data points.');
                    end
                                
            
                catch
                    % Display a warning pop-up message if an error occurs
                    warndlg('Error reading the motion file data. Missing a column of data or data contains non-numerical value.');
                end
            end
        end

        % Button pushed function: SaveValuesButton
        function EvaluateS1Position(app, event)
            % Get the entered value from the EditField
            inputValueP1x = app.P1xEditField.Value;
            inputValueP1y = app.P1yEditField.Value;
            inputValueP2x = app.P2xEditField.Value;
            inputValueP2y = app.P2yEditField.Value;

                % Check if the x values are between 0 and 100  
                % Check if the y values are between 0 and 40

                if inputValueP1x < 0 || inputValueP1x > 100
                % Show a warning message
                warndlg('Invalid P1-x input. Please enter a number between 0 and 100.', 'Warning', 'modal');
                end

                if inputValueP2x < 0 || inputValueP2x > 100
                % Show a warning message
                warndlg('Invalid P2-x input. Please enter a number between 0 and 100.', 'Warning', 'modal');
                end

                if inputValueP1y < 0 || inputValueP1y > 40
                % Show a warning message
                warndlg('Invalid P1-y input. Please enter a number between 0 and 40.', 'Warning', 'modal');
                end

                if inputValueP2y < 0 || inputValueP2y > 40
                % Show a warning message
                warndlg('Invalid P2-y input. Please enter a number between 0 and 40.', 'Warning', 'modal');
                end


        end

        % Button pushed function: SaveValuesButton_2
        function EvaluateS2Position(app, event)
            % Get the entered value from the EditField
            inputValueP1x = app.P1xEditField_4.Value;
            inputValueP1y = app.P1yEditField_4.Value;
            inputValueP2x = app.P2xEditField_4.Value;
            inputValueP2y = app.P2yEditField_4.Value;

                % Check if the x values are between 0 and 100  
                % Check if the y values are between 0 and 40

                if inputValueP1x < 0 || inputValueP1x > 100
                % Show a warning message
                warndlg('Invalid P1-x input. Please enter a number between 0 and 100.', 'Warning', 'modal');
                end

                if inputValueP2x < 0 || inputValueP2x > 100
                % Show a warning message
                warndlg('Invalid P2-x input. Please enter a number between 0 and 100.', 'Warning', 'modal');
                end

                if inputValueP1y < 0 || inputValueP1y > 40
                % Show a warning message
                warndlg('Invalid P1-y input. Please enter a number between 0 and 40.', 'Warning', 'modal');
                end

                if inputValueP2y < 0 || inputValueP2y > 40
                % Show a warning message
                warndlg('Invalid P2-y input. Please enter a number between 0 and 40.', 'Warning', 'modal');
                end

        end

        % Button pushed function: SaveValuesButton_3
        function EvaluateRelativeDistance(app, event)
            % Get the entered value from the EditField
            xValue = app.xEditField.Value;
            yValue = app.yEditField.Value;
            zValue = app.zEditField.Value;
            yawValue = app.yawEditField_2.Value;
            pitchValue = app.pitchEditField.Value;
            rollValue = app.rollEditField.Value;
            
                % Max displacement 9.79cm in the x-direction, 27.534cm in the y-direction, and 3.025cm in the z-direction  
                % Check if the relative distance is within twice the max displacement

                if xValue < 0 || xValue > (9.79*2)
                % Show a warning message
                warndlg('Invalid x input. Out of range.', 'Warning', 'modal');
                end
                
                if yValue < 0 || yValue > (27.534*2)
                % Show a warning message
                warndlg('Invalid y input. Out of range.', 'Warning', 'modal');
                end

                if zValue < 0 || zValue > (3.025*2)
                % Show a warning message
                warndlg('Invalid z input. Out of range.', 'Warning', 'modal');
                end

                if yawValue < 0 || yawValue > (9.79*2)
                % Show a warning message
                warndlg('Invalid yaw input. Out of range.', 'Warning', 'modal');
                end
                
                if pitchValue < 0 || pitchValue > (27.534*2)
                % Show a warning message
                warndlg('Invalid pitch input. Out of range.', 'Warning', 'modal');
                end

                if rollValue < 0 || rollValue > (3.025*2)
                % Show a warning message
                warndlg('Invalid roll input. Out of range.', 'Warning', 'modal');
                end

        end

        % Button pushed function: SetParametersButtonPitch, 
        % ...and 5 other components
        function openParameterSettingWindow(app, event)
            sourceButton = event.Source;
            buttonTag = sourceButton.Tag;
            switch buttonTag
                case 'ChooseFunctionDropDownX'
                    functionTag = app.ChooseFunctionDropDownX.Value;
                case 'ChooseFunctionDropDownY'
                    functionTag = app.ChooseFunctionDropDownY.Value;
                case 'ChooseFunctionDropDownZ'
                    functionTag = app.ChooseFunctionDropDownZ.Value;
                case 'ChooseFunctionDropDownYaw'
                    functionTag = app.ChooseFunctionDropDownYaw.Value;
                case 'ChooseFunctionDropDownPitch'
                    functionTag = app.ChooseFunctionDropDownPitch.Value;
                case 'ChooseFunctionDropDownRoll'
                    functionTag = app.ChooseFunctionDropDownRoll.Value;
            end

            switch functionTag
                case 'Sine'
                    
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CoordinatesofStewartPlatformsS1andS2Label = uilabel(app.UIFigure);
            app.CoordinatesofStewartPlatformsS1andS2Label.Text = 'Coordinates of Stewart Platforms (S1 and S2):';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.S1Tab = uitab(app.TabGroup);
            app.S1Tab.Title = 'S1';
            app.P1xEditFieldLabel = uilabel(app.S1Tab);
            app.P1xEditFieldLabel.Text = 'P1-x';
            app.P1xEditField = uieditfield(app.S1Tab, 'numeric');
            app.P1xEditField.ValueDisplayFormat = '%.3f';
            app.P1yEditFieldLabel = uilabel(app.S1Tab);
            app.P1yEditFieldLabel.Text = 'P1-y';
            app.P1yEditField = uieditfield(app.S1Tab, 'numeric');
            app.P1yEditField.ValueDisplayFormat = '%.3f';
            app.P2xEditFieldLabel = uilabel(app.S1Tab);
            app.P2xEditFieldLabel.Text = 'P2-x';
            app.P2xEditField = uieditfield(app.S1Tab, 'numeric');
            app.P2xEditField.ValueDisplayFormat = '%.3f';
            app.P2yEditFieldLabel = uilabel(app.S1Tab);
            app.P2yEditFieldLabel.Text = 'P2-y';
            app.P2yEditField = uieditfield(app.S1Tab, 'numeric');
            app.P2yEditField.ValueDisplayFormat = '%.3f';
            app.SaveValuesButton = uibutton(app.S1Tab, 'push');
            app.SaveValuesButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateS1Position, true);
            app.SaveValuesButton.Text = 'Save Values';
            app.S2Tab = uitab(app.TabGroup);
            app.S2Tab.Title = 'S2';
            app.P1xEditField_4Label = uilabel(app.S2Tab);
            app.P1xEditField_4Label.Text = 'P1-x';
            app.P1xEditField_4 = uieditfield(app.S2Tab, 'numeric');
            app.P1yEditField_4Label = uilabel(app.S2Tab);
            app.P1yEditField_4Label.Text = 'P1-y';
            app.P1yEditField_4 = uieditfield(app.S2Tab, 'numeric');
            app.P2xEditField_4Label = uilabel(app.S2Tab);
            app.P2xEditField_4Label.Text = 'P2-x';
            app.P2xEditField_4 = uieditfield(app.S2Tab, 'numeric');
            app.P2yEditField_4Label = uilabel(app.S2Tab);
            app.P2yEditField_4Label.Text = 'P2-y';
            app.P2yEditField_4 = uieditfield(app.S2Tab, 'numeric');
            app.SaveValuesButton_2 = uibutton(app.S2Tab, 'push');
            app.SaveValuesButton_2.ButtonPushedFcn = createCallbackFcn(app, @EvaluateS2Position, true);
            app.SaveValuesButton_2.Text = 'Save Values';
            app.InitialRelativePositionofTargetsrelativetoS1Label = uilabel(app.UIFigure);
            app.InitialRelativePositionofTargetsrelativetoS1Label.Text = 'Initial Relative Position of Targets (relative to S1):';
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.S2toS1Tab = uitab(app.TabGroup_2);
            app.S2toS1Tab.Title = 'S2 to S1';
            app.xEditFieldLabel = uilabel(app.S2toS1Tab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.S2toS1Tab, 'numeric');
            app.xEditField.ValueDisplayFormat = '%.3f';
            app.yEditFieldLabel = uilabel(app.S2toS1Tab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.S2toS1Tab, 'numeric');
            app.yEditField.ValueDisplayFormat = '%.3f';
            app.yawEditField_2Label = uilabel(app.S2toS1Tab);
            app.yawEditField_2Label.Text = 'yaw';
            app.yawEditField_2 = uieditfield(app.S2toS1Tab, 'numeric');
            app.yawEditField_2.ValueDisplayFormat = '%.3f';
            app.pitchEditFieldLabel = uilabel(app.S2toS1Tab);
            app.pitchEditFieldLabel.Text = 'pitch';
            app.pitchEditField = uieditfield(app.S2toS1Tab, 'numeric');
            app.pitchEditField.ValueDisplayFormat = '%.3f';
            app.zEditFieldLabel = uilabel(app.S2toS1Tab);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.S2toS1Tab, 'numeric');
            app.zEditField.ValueDisplayFormat = '%.3f';
            app.rollEditFieldLabel = uilabel(app.S2toS1Tab);
            app.rollEditFieldLabel.Text = 'roll';
            app.rollEditField = uieditfield(app.S2toS1Tab, 'numeric');
            app.rollEditField.ValueDisplayFormat = '%.3f';
            app.SaveValuesButton_3 = uibutton(app.S2toS1Tab, 'push');
            app.SaveValuesButton_3.ButtonPushedFcn = createCallbackFcn(app, @EvaluateRelativeDistance, true);
            app.SaveValuesButton_3.Text = 'Save Values';
            app.MotionFileLabel = uilabel(app.UIFigure);
            app.MotionFileLabel.Text = 'Motion File:';
            app.VerifyMotionLabel = uilabel(app.UIFigure);
            app.VerifyMotionLabel.Text = 'Verify Motion:';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.Text = 'Start';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.CustomTab = uitab(app.TabGroup2);
            app.CustomTab.Title = 'Custom';
            app.TabGroup_3 = uitabgroup(app.CustomTab);
            app.S1Tab_2 = uitab(app.TabGroup_3);
            app.S1Tab_2.Title = 'S1';
            app.FileLocationEditFieldLabel = uilabel(app.S1Tab_2);
            app.FileLocationEditFieldLabel.Text = 'File Location';
            app.FileLocationEditField = uieditfield(app.S1Tab_2, 'text');
            app.UploadButton = uibutton(app.S1Tab_2, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.S2Tab_2 = uitab(app.TabGroup_3);
            app.S2Tab_2.Title = 'S2';
            app.UploadButton_2 = uibutton(app.S2Tab_2, 'push');
            app.UploadButton_2.ButtonPushedFcn = createCallbackFcn(app, @UploadButton_2Pushed, true);
            app.UploadButton_2.Text = 'Upload';
            app.FileLocationEditField_2Label = uilabel(app.S2Tab_2);
            app.FileLocationEditField_2Label.Text = 'File Location';
            app.FileLocationEditField_2 = uieditfield(app.S2Tab_2, 'text');
            app.PredefinedTab = uitab(app.TabGroup2);
            app.PredefinedTab.Title = 'Predefined';
            app.TabGroup3 = uitabgroup(app.PredefinedTab);
            app.xTab = uitab(app.TabGroup3);
            app.xTab.Title = 'x';
            app.ChooseFunctionDropDownLabel = uilabel(app.xTab);
            app.ChooseFunctionDropDownLabel.Text = 'Choose Function';
            app.ChooseFunctionDropDownX = uidropdown(app.xTab);
            app.ChooseFunctionDropDownX.Items = {'Sine'};
            app.ChooseFunctionDropDownX.Value = 'Sine';
            app.SetParametersButtonX = uibutton(app.xTab, 'push');
            app.SetParametersButtonX.ButtonPushedFcn = createCallbackFcn(app, @openParameterSettingWindow, true);
            app.SetParametersButtonX.Text = 'Set Parameters';
            app.yTab = uitab(app.TabGroup3);
            app.yTab.Title = 'y';
            app.SetParametersButtonY = uibutton(app.yTab, 'push');
            app.SetParametersButtonY.ButtonPushedFcn = createCallbackFcn(app, @openParameterSettingWindow, true);
            app.SetParametersButtonY.Text = 'Set Parameters';
            app.ChooseFunctionDropDown_12Label = uilabel(app.yTab);
            app.ChooseFunctionDropDown_12Label.Text = 'Choose Function';
            app.ChooseFunctionDropDownY = uidropdown(app.yTab);
            app.ChooseFunctionDropDownY.Items = {'Sine'};
            app.ChooseFunctionDropDownY.Value = 'Sine';
            app.zTab = uitab(app.TabGroup3);
            app.zTab.Title = 'z';
            app.SetParametersButtonZ = uibutton(app.zTab, 'push');
            app.SetParametersButtonZ.ButtonPushedFcn = createCallbackFcn(app, @openParameterSettingWindow, true);
            app.SetParametersButtonZ.Text = 'Set Parameters';
            app.ChooseFunctionDropDown_13Label = uilabel(app.zTab);
            app.ChooseFunctionDropDown_13Label.Text = 'Choose Function';
            app.ChooseFunctionDropDownZ = uidropdown(app.zTab);
            app.ChooseFunctionDropDownZ.Items = {'Sine'};
            app.ChooseFunctionDropDownZ.Value = 'Sine';
            app.yawTab = uitab(app.TabGroup3);
            app.yawTab.Title = 'yaw';
            app.SetParametersButtonYaw = uibutton(app.yawTab, 'push');
            app.SetParametersButtonYaw.ButtonPushedFcn = createCallbackFcn(app, @openParameterSettingWindow, true);
            app.SetParametersButtonYaw.Text = 'Set Parameters';
            app.ChooseFunctionDropDown_14Label = uilabel(app.yawTab);
            app.ChooseFunctionDropDown_14Label.Text = 'Choose Function';
            app.ChooseFunctionDropDownYaw = uidropdown(app.yawTab);
            app.ChooseFunctionDropDownYaw.Items = {'Sine'};
            app.ChooseFunctionDropDownYaw.Value = 'Sine';
            app.pitchTab = uitab(app.TabGroup3);
            app.pitchTab.Title = 'pitch';
            app.SetParametersButtonPitch = uibutton(app.pitchTab, 'push');
            app.SetParametersButtonPitch.ButtonPushedFcn = createCallbackFcn(app, @openParameterSettingWindow, true);
            app.SetParametersButtonPitch.Text = 'Set Parameters';
            app.ChooseFunctionDropDown_15Label = uilabel(app.pitchTab);
            app.ChooseFunctionDropDown_15Label.Text = 'Choose Function';
            app.ChooseFunctionDropDownPitch = uidropdown(app.pitchTab);
            app.ChooseFunctionDropDownPitch.Items = {'Sine'};
            app.ChooseFunctionDropDownPitch.Value = 'Sine';
            app.rollTab = uitab(app.TabGroup3);
            app.rollTab.Title = 'roll';
            app.SetParametersButtonRoll = uibutton(app.rollTab, 'push');
            app.SetParametersButtonRoll.ButtonPushedFcn = createCallbackFcn(app, @openParameterSettingWindow, true);
            app.SetParametersButtonRoll.Text = 'Set Parameters';
            app.ChooseFunctionDropDown_16Label = uilabel(app.rollTab);
            app.ChooseFunctionDropDown_16Label.Text = 'Choose Function';
            app.ChooseFunctionDropDownRoll = uidropdown(app.rollTab);
            app.ChooseFunctionDropDownRoll.Items = {'Sine'};
            app.ChooseFunctionDropDownRoll.Value = 'Sine';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = funcUploadButtonPushedChecksData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end